classdef CurveTracer < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CurveTracerUIFigure    matlab.ui.Figure
        InterpolButtonGroup    matlab.ui.container.ButtonGroup
        spline4pointsButton    matlab.ui.control.RadioButton
        makima2pointsButton    matlab.ui.control.RadioButton
        LinearButton           matlab.ui.control.RadioButton
        XIntervalSpinner       matlab.ui.control.Spinner
        XIntervalSpinnerLabel  matlab.ui.control.Label
        EquiDistValuesButton   matlab.ui.control.Button
        ScaleButtonGroup       matlab.ui.container.ButtonGroup
        Log10XLog10YButton     matlab.ui.control.RadioButton
        Log10XLinearYButton    matlab.ui.control.RadioButton
        LinearXLog10YButton    matlab.ui.control.RadioButton
        LinearXLinearYButton   matlab.ui.control.RadioButton
        GetValuesButton        matlab.ui.control.Button
        ReferencepointsButton  matlab.ui.control.Button
        TraceCurveButton       matlab.ui.control.Button
        AddImageButton         matlab.ui.control.Button
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        image; % Description
        curveTrace;
        refPointsHandle = [];
        refPoints;
        nrRefPoints = 0;
        fitPoints;
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Add Current Path to Matlab
            addpath(pwd);

            % Remove Axis Scale
            app.UIAxes.XAxis.Visible = 'off';
            app.UIAxes.YAxis.Visible = 'off';
        end

        % Button pushed function: AddImageButton
        function AddImageButtonPushed(app, event)
            [filename, filepath] = uigetfile('*.jpg', 'Select an image file');

            if filename ~= 0
                imagePath = fullfile(filepath, filename);

                delete(app.curveTrace)
                delete(app.refPointsHandle)
                app.refPoints = [];

                app.image = imread(imagePath);
                imagesc(app.image,'Parent',app.UIAxes);
            end
        end

        % Button pushed function: TraceCurveButton
        function TraceCurveButtonPushed(app, event)
            % Check if exist a curve
            if ~isempty(app.curveTrace) && isvalid(app.curveTrace)
                delete(app.curveTrace)
            end
            app.curveTrace = drawpolyline(app.UIAxes);
        end

        % Button pushed function: ReferencepointsButton
        function ReferencepointsButtonPushed(app, event)
            % Add New Point
            newPoint = drawpoint(app.UIAxes,...
                                'Color',[1 0 0],...
                                'MarkerSize',4);

            % Input X and Y Values
            prompt = {'Enter X position:','Enter Y Position:'};
            dlgtitle = 'Input';
            dims = [1 35];
            definput = {'0','0'};
            answer = inputdlg(prompt,dlgtitle,dims,definput);

            if isempty(answer)
                delete(newPoint);
            else
                newPoint.Label = [' (' answer{1} ';' answer{2} ')'];
                newPoint.LabelAlpha = 0.15;


                nrPts = app.nrRefPoints + 1;
                app.refPointsHandle = [app.refPointsHandle;newPoint];
                
                app.refPoints(nrPts,1) = newPoint.Position(1);
                app.refPoints(nrPts,2) = newPoint.Position(2);
                app.refPoints(nrPts,3) = str2double(answer{1});
                app.refPoints(nrPts,4) = str2double(answer{2});

                app.nrRefPoints = nrPts;
            end
            
        end

        % Button pushed function: GetValuesButton
        function GetValuesButtonPushed(app, event)
            
            % Get Valid Reference Points
            newRefPoints = [];
            k = 0;
            for i = 1:app.nrRefPoints
                if isvalid(app.refPointsHandle(i))
                    k = k + 1;
                    % get current positions
                    newRefPoints(k,1) = app.refPointsHandle(i).Position(1);
                    newRefPoints(k,2) = app.refPointsHandle(i).Position(2);
                    newRefPoints(k,3) = app.refPoints(i,3);
                    newRefPoints(k,4) = app.refPoints(i,4);
                end
            end

            uniqueRefValuesX = size(unique(newRefPoints(:,3)),1);
            uniqueRefValuesY = size(unique(newRefPoints(:,4)),1);

            % Get Curve Trace Points
            curvePoints = app.curveTrace.Position;
            if isempty(curvePoints)
                msg = {'No curve trace points available.'};
                errordlg(msg);
                return;
            end
            
            % Set Fit Type
            if app.LinearXLinearYButton.Value
                if uniqueRefValuesX >= 2 && uniqueRefValuesY >= 2
                    % Fit Reference Points for Axis XX
                    points = newRefPoints(:,1);
                    realValues = newRefPoints(:,3);
    
                    coefficients = polyfit(points,realValues,1);    % Fit a 1st-degree polynomial (linear fit).
                    
                    xSlope = coefficients(1);                       % Slope of the linear fit.
                    xIntercept = coefficients(2);                   % y-intercept of the linear fit.
    
                    % Fit Reference Points for Axis YY
                    points = newRefPoints(:,2);
                    realValues = newRefPoints(:,4);
    
                    coefficients = polyfit(points,realValues,1);    % Fit a 1st-degree polynomial (linear fit).
                    
                    ySlope = coefficients(1);                       % Slope of the linear fit.
                    yIntercept = coefficients(2);                   % y-intercept of the linear fit.
                else
                    msg = {'Linear fit needs at least 2 different values in X and Y.'};
                    errordlg(msg);
                    return;
                end

                curvePoints = app.curveTrace.Position;
                curvePoints(:,1) = (xSlope*curvePoints(:,1)) + xIntercept;
                curvePoints(:,2) = (ySlope*curvePoints(:,2)) + yIntercept;

            elseif app.LinearXLog10YButton.Value
                if uniqueRefValuesX >= 2 && uniqueRefValuesY >= 3
                    % Fit Reference Points for Axis XX
                    points = newRefPoints(:,1);
                    realValues = newRefPoints(:,3);
    
                    coefficients = polyfit(points,realValues,1);    % Fit a 1st-degree polynomial (linear fit).
                    
                    xSlope = coefficients(1);                       % Slope of the linear fit.
                    xIntercept = coefficients(2);                   % y-intercept of the linear fit.
    
                    % Fit Reference Points for Axis YY
                    points = newRefPoints(:,2);
                    realValues = log10(newRefPoints(:,4));
    
                    coefficients = polyfit(points,realValues,1);    % Fit a 1st-degree polynomial (linear fit).
                    
                    ySlope = coefficients(1);                       % Slope of the linear fit.
                    yIntercept = coefficients(2);                   % y-intercept of the linear fit.
                else
                    msg = {'Linear fit needs at least 2 different values in X.';...
                           'Log fit needs at least 3 different values in Y.'};
                    errordlg(msg);
                    return;
                end

                curvePoints = app.curveTrace.Position;
                curvePoints(:,1) = (xSlope*curvePoints(:,1)) + xIntercept;
                curvePoints(:,2) = 10.^((ySlope*curvePoints(:,2)) + yIntercept);

            elseif app.Log10XLinearYButton.Value
                if uniqueRefValuesX >= 3 && uniqueRefValuesY >= 2
                    % Fit Reference Points for Axis XX
                    points = newRefPoints(:,1);
                    realValues = log10(newRefPoints(:,3));
    
                    coefficients = polyfit(points,realValues,1);    % Fit a 1st-degree polynomial (linear fit).
                    
                    xSlope = coefficients(1);                       % Slope of the linear fit.
                    xIntercept = coefficients(2);                   % y-intercept of the linear fit.
    
                    % Fit Reference Points for Axis YY
                    points = newRefPoints(:,2);
                    realValues = newRefPoints(:,4);
    
                    coefficients = polyfit(points,realValues,1);    % Fit a 1st-degree polynomial (linear fit).
                    
                    ySlope = coefficients(1);                       % Slope of the linear fit.
                    yIntercept = coefficients(2);                   % y-intercept of the linear fit.
                else
                    msg = {'Log fit needs at least 3 different values in X.';...
                           'Linear fit needs at least 2 different values in Y.'};
                    errordlg(msg);
                    return;
                end
                
                curvePoints = app.curveTrace.Position;
                curvePoints(:,1) = 10.^((xSlope*curvePoints(:,1)) + xIntercept);
                curvePoints(:,2) = (ySlope*curvePoints(:,2)) + yIntercept;

            elseif app.Log10XLog10YButton.Value
                if uniqueRefValuesX >= 3 && uniqueRefValuesY >= 3
                    % Fit Reference Points for Axis XX
                    points = newRefPoints(:,1);
                    realValues = log10(newRefPoints(:,3));
    
                    coefficients = polyfit(points,realValues,1);    % Fit a 1st-degree polynomial (linear fit).
                    
                    xSlope = coefficients(1);                       % Slope of the linear fit.
                    xIntercept = coefficients(2);                   % y-intercept of the linear fit.
    
                    % Fit Reference Points for Axis YY
                    points = newRefPoints(:,2);
                    realValues = log10(newRefPoints(:,4));
    
                    coefficients = polyfit(points,realValues,1);    % Fit a 1st-degree polynomial (linear fit).
                    
                    ySlope = coefficients(1);                       % Slope of the linear fit.
                    yIntercept = coefficients(2);                   % y-intercept of the linear fit.
                else
                    msg = {'Log fit needs at least 3 different values in X and Y.'};
                    errordlg(msg);
                    return;
                end

                curvePoints = app.curveTrace.Position;
                curvePoints(:,1) = 10.^((xSlope*curvePoints(:,1)) + xIntercept);
                curvePoints(:,2) = 10.^((ySlope*curvePoints(:,2)) + yIntercept);
            end

            %
            app.fitPoints = curvePoints;

            % Create Table to Show Values
            uitable('Data',curvePoints);

        end

        % Button pushed function: EquiDistValuesButton
        function EquiDistValuesButtonPushed(app, event)
            
            if ~isempty(app.fitPoints)

                if app.XIntervalSpinner.Value > 0
                    intervalXValue = app.XIntervalSpinner.Value;
                else
                    msg = {'Interval value has to be higher then 0.'};
                    errordlg(msg);
                    return;
                end

                % Get Closes Multiple Values
                aux_initialXValue = round(app.fitPoints(1,1)/intervalXValue) * intervalXValue;
                aux_finalXValue = round(app.fitPoints(end,1)/intervalXValue) * intervalXValue;

                if aux_initialXValue < aux_finalXValue
                    initialXValue = aux_initialXValue;
                    finalXValue = aux_finalXValue;
                else
                    finalXValue = aux_initialXValue;
                    initialXValue = aux_finalXValue;
                end
                
                % Equally Spaced X Values
                interpolatedPointsX = initialXValue:intervalXValue:finalXValue;

                % Check if their is not repeated values of X
                sizePointsX = size(app.fitPoints(:,1),1);
                uniquePointsX = unique(app.fitPoints(:,1));
                sizeUniquePointsX = size(uniquePointsX,1);

                if sizePointsX == sizeUniquePointsX
                    % Interpolation
                    if app.LinearButton.Value
                        interpolatedPointsY = interp1(app.fitPoints(:,1),app.fitPoints(:,2),interpolatedPointsX,'linear');
                    elseif app.makima2pointsButton.Value
                        interpolatedPointsY = interp1(app.fitPoints(:,1),app.fitPoints(:,2),interpolatedPointsX,'makima');
                    elseif app.spline4pointsButton.Value
                        interpolatedPointsY = interp1(app.fitPoints(:,1),app.fitPoints(:,2),interpolatedPointsX,'spline');
                    end
    
                    % Create Table to Show Values
                    interpolatedPoints(:,1) = interpolatedPointsX;
                    interpolatedPoints(:,2) = interpolatedPointsY;
                    uitable('Data',interpolatedPoints);
                else
                    msg = {'All xx values in the curve have to be different.'};
                    errordlg(msg);
                    return;
                end

                
            else
                msg = {'Get values first.'};
                errordlg(msg);
                return;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CurveTracerUIFigure = uifigure('Visible', 'off');
            app.CurveTracerUIFigure.Name = 'Curve Tracer';
            app.UIAxes = uiaxes(app.CurveTracerUIFigure);
            app.UIAxes.XLimitMethod = 'tight';
            app.UIAxes.YLimitMethod = 'tight';
            app.UIAxes.ZLimitMethod = 'tight';
            app.AddImageButton = uibutton(app.CurveTracerUIFigure, 'push');
            app.AddImageButton.ButtonPushedFcn = createCallbackFcn(app, @AddImageButtonPushed, true);
            app.AddImageButton.Text = {'Add'; 'Image'};
            app.TraceCurveButton = uibutton(app.CurveTracerUIFigure, 'push');
            app.TraceCurveButton.ButtonPushedFcn = createCallbackFcn(app, @TraceCurveButtonPushed, true);
            app.TraceCurveButton.Text = {'Trace'; 'Curve'};
            app.ReferencepointsButton = uibutton(app.CurveTracerUIFigure, 'push');
            app.ReferencepointsButton.ButtonPushedFcn = createCallbackFcn(app, @ReferencepointsButtonPushed, true);
            app.ReferencepointsButton.Text = {'Reference'; 'points'};
            app.GetValuesButton = uibutton(app.CurveTracerUIFigure, 'push');
            app.GetValuesButton.ButtonPushedFcn = createCallbackFcn(app, @GetValuesButtonPushed, true);
            app.GetValuesButton.Text = {'Get'; 'Values'};
            app.ScaleButtonGroup = uibuttongroup(app.CurveTracerUIFigure);
            app.ScaleButtonGroup.Title = 'Scale';
            app.LinearXLinearYButton = uiradiobutton(app.ScaleButtonGroup);
            app.LinearXLinearYButton.Text = {'Linear X'; 'Linear Y'};
            app.LinearXLinearYButton.Value = true;
            app.LinearXLog10YButton = uiradiobutton(app.ScaleButtonGroup);
            app.LinearXLog10YButton.Text = {'Linear X'; 'Log10 Y'};
            app.Log10XLinearYButton = uiradiobutton(app.ScaleButtonGroup);
            app.Log10XLinearYButton.Text = {'Log10 X'; 'Linear Y'};
            app.Log10XLog10YButton = uiradiobutton(app.ScaleButtonGroup);
            app.Log10XLog10YButton.Text = {'Log10 X'; 'Log10 Y'};
            app.EquiDistValuesButton = uibutton(app.CurveTracerUIFigure, 'push');
            app.EquiDistValuesButton.ButtonPushedFcn = createCallbackFcn(app, @EquiDistValuesButtonPushed, true);
            app.EquiDistValuesButton.Text = {'Equi-Dist.'; 'Values'};
            app.XIntervalSpinnerLabel = uilabel(app.CurveTracerUIFigure);
            app.XIntervalSpinnerLabel.Text = 'X Interval';
            app.XIntervalSpinner = uispinner(app.CurveTracerUIFigure);
            app.XIntervalSpinner.Limits = [0 Inf];
            app.InterpolButtonGroup = uibuttongroup(app.CurveTracerUIFigure);
            app.InterpolButtonGroup.Title = 'Interpol.:';
            app.LinearButton = uiradiobutton(app.InterpolButtonGroup);
            app.LinearButton.Text = 'Linear';
            app.LinearButton.Value = true;
            app.makima2pointsButton = uiradiobutton(app.InterpolButtonGroup);
            app.makima2pointsButton.Text = {'makima'; '2+ points'};
            app.spline4pointsButton = uiradiobutton(app.InterpolButtonGroup);
            app.spline4pointsButton.Text = {'spline'; '4 points'};
            % Show the figure after all components are created
            app.CurveTracerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CurveTracer

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CurveTracerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CurveTracerUIFigure)
        end
    end
end